classdef ProyectoFinal_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GuardardatosButton             matlab.ui.control.Button
        SubirdatosButton               matlab.ui.control.Button
        ModoSwitch                     matlab.ui.control.Switch
        ModoSwitchLabel                matlab.ui.control.Label
        mientrasmasseacercaa1mejoreslaaproximacionLabel  matlab.ui.control.Label
        r2                             matlab.ui.control.Label
        R2Label                        matlab.ui.control.Label
        NumerodevaloresEditField       matlab.ui.control.NumericEditField
        NumerodevaloresEditFieldLabel  matlab.ui.control.Label
        CoeficientededeterminacionLabel  matlab.ui.control.Label
        b                              matlab.ui.control.Label
        m                              matlab.ui.control.Label
        bLabel                         matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        ymxbLabel                      matlab.ui.control.Label
        EcuaciondelarectaLabel         matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function graphData(app)
            % Get table data and store it inside variables
            tableData = app.UITable.Data;
            x = tableData(:,1);
            y = tableData(:,2);

            % Scatter plot our data
            scatter(app.UIAxes,x,y,35,"black","filled","o");
            
            % Prepare graph to plot the trend line
            hold(app.UIAxes, 'on');
        end
        
        function [trendLine,m,b] = linearRegression(app)
            % Get table data and store it inside variables
            tableData = app.UITable.Data;
            x = tableData(:, 1);
            y = tableData(:, 2);

             % Perform linear regression using polyfit
            p = polyfit(x, y, 1);
            b = p(2);
            m = p(1);

            % Calculate trend line values
            trendLine = b + m*x;
        end
        
        function applyChanges(app)
            % Get table data and store it inside variables
            tableData = app.UITable.Data;
            x = tableData(:, 1);
            y = tableData(:, 2);

            % Clear existing plot in UIAxes
            cla(app.UIAxes);

            % Graph user input data as dots
            graphData(app);

            % Calculate value of our linear regression
            [trendLine, m, b] = linearRegression(app);
            
            % Assign labels
            app.b.Text=sprintf('%.6f',b);
            app.m.Text=sprintf('%.6f',m);
            
            % Draw trend line
            plot(app.UIAxes,app.UITable.Data(:,1),trendLine);
            % Release hold on UIAxes
            hold(app.UIAxes, 'off');

            % Set axis limits
            setAxisLimits(app);

            % Calculate and show determination coefficient
            r2 = coefficentOfDetermination(app,y,trendLine);
            app.r2.Text= sprintf('%.6f',r2);
        end

        function setAxisLimits(app)
            % Get table data and store it inside variables
            tableData = app.UITable.Data;
            x = tableData(:, 1);
            y = tableData(:, 2);

            % Find minimium element of each vector
            xMin = min(x);
            yMin = min(y);
            
            % When we have different values
            if(max(x) ~= xMin)
                xRange = max(x) - xMin;
            % When we have the same values we stablish a range of 1
            else
                xRange = 1;
            end

            % When we have different values
            if(max(y) ~= yMin)
                yRange = max(y) - yMin;
            % When we have the same values we stablish a range of 1
            else
                yRange = 1;
            end

            % Stablish axes with some padding
            app.UIAxes.XLim = [xMin - 0.1 * xRange, max(x) + 0.1 * xRange];
            app.UIAxes.YLim = [yMin - 0.1 * yRange, max(y) + 0.1 * yRange];
        end
        
        function rSquared = coefficentOfDetermination(~, y, yFit)
            % Calculate the mean of y
            yMean = mean(y);

            % Calculate the total sum of squares
            ssTotal = sum((y - yMean).^2);

            % Calculate the residual sum of squares
            ssResidual = sum((y - yFit).^2);

            % Calculate the coefficient of determination (R-squared)
            rSquared = 1 - ssResidual/ssTotal;
        end
        
        function manualModeStart(app)
            % Clear previous records and hide values
            cla(app.UIAxes);
            app.NumerodevaloresEditField.Visible="on";
            app.NumerodevaloresEditFieldLabel.Visible="on";
            app.GuardardatosButton.Visible="off";
            app.SubirdatosButton.Visible="off";

            % Change axis names
            app.UITable.ColumnName=["X","Y"];

            % Stablish default values
            app.NumerodevaloresEditField.Value=10;
            for i=1:10
                app.UITable.Data(i,1) = 0;
                app.UITable.Data(i,2) = 0;
            end
            applyChanges(app);
        end
        
        function excelModeStart(app)
            % Clear previous records and hide values
            cla(app.UIAxes);
            app.NumerodevaloresEditField.Visible="off";
            app.NumerodevaloresEditFieldLabel.Visible="off";
            app.GuardardatosButton.Visible="on";
            app.SubirdatosButton.Visible="on";

            % Change axis names
            app.UITable.ColumnName=["GRE","% COA"];

            % Load datasheets values
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            manualModeStart(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            applyChanges(app);
        end

        % Value changed function: NumerodevaloresEditField
        function NumerodevaloresEditFieldValueChanged(app, event)
            % Store new and old number of columns
            tableData = app.UITable.Data;
            currentRows = size(tableData,1);
            newRows = app.NumerodevaloresEditField.Value;

            % In case we need to add more columns
            if(currentRows < newRows)
                for i=currentRows:newRows
                    app.UITable.Data(i,1) = 0;
                    app.UITable.Data(i,2) = 0;
                end
                applyChanges(app);
            
            % In case we need to delete columns
            elseif (currentRows > newRows)
                for i=newRows:currentRows-1
                    app.UITable.Data(end,:)=[];
                end
                applyChanges(app);
            end

            
        end

        % Value changed function: ModoSwitch
        function ModoSwitchValueChanged(app, event)
            if app.ModoSwitch.Value == "Excel"
                excelModeStart(app);
            else
                manualModeStart(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'grafiphic.jpg');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Multiselect = 'off';
            app.EcuaciondelarectaLabel = uilabel(app.UIFigure);
            app.EcuaciondelarectaLabel.Text = 'Ecuacion de la recta';
            app.ymxbLabel = uilabel(app.UIFigure);
            app.ymxbLabel.Text = 'y=mx+b';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm:';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b:';
            app.m = uilabel(app.UIFigure);
            app.m.Text = '0';
            app.b = uilabel(app.UIFigure);
            app.b.Text = '0';
            app.CoeficientededeterminacionLabel = uilabel(app.UIFigure);
            app.CoeficientededeterminacionLabel.Text = 'Coeficiente de determinacion';
            app.NumerodevaloresEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodevaloresEditFieldLabel.Text = 'Numero de valores';
            app.NumerodevaloresEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumerodevaloresEditField.Limits = [2 Inf];
            app.NumerodevaloresEditField.RoundFractionalValues = 'on';
            app.NumerodevaloresEditField.ValueChangedFcn = createCallbackFcn(app, @NumerodevaloresEditFieldValueChanged, true);
            app.NumerodevaloresEditField.Value = 10;
            app.R2Label = uilabel(app.UIFigure);
            app.R2Label.Text = 'R2:';
            app.r2 = uilabel(app.UIFigure);
            app.r2.Text = '0';
            app.mientrasmasseacercaa1mejoreslaaproximacionLabel = uilabel(app.UIFigure);
            app.mientrasmasseacercaa1mejoreslaaproximacionLabel.Text = '(mientras mas se acerca a 1, mejor es la aproximacion)';
            app.ModoSwitchLabel = uilabel(app.UIFigure);
            app.ModoSwitchLabel.Text = 'Modo';
            app.ModoSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModoSwitch.Items = {'Manual', 'Excel'};
            app.ModoSwitch.ValueChangedFcn = createCallbackFcn(app, @ModoSwitchValueChanged, true);
            app.ModoSwitch.Value = 'Manual';
            app.SubirdatosButton = uibutton(app.UIFigure, 'push');
            app.SubirdatosButton.Text = 'Subir datos';
            app.GuardardatosButton = uibutton(app.UIFigure, 'push');
            app.GuardardatosButton.Text = 'Guardar datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoFinal_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end